classdef sm_boom_lift_crawler_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BoomLiftCrawlerAppUIFigure     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TestTab                        matlab.ui.container.Tab
        PlotTestSequenceLabel          matlab.ui.control.Label
        OperatorButton                 matlab.ui.control.Button
        PlotControlSequenceCodeButton  matlab.ui.control.Button
        PlotMotionSequenceCodeButton   matlab.ui.control.Button
        MotionButton                   matlab.ui.control.Button
        PlotValveSequenceCodeButton    matlab.ui.control.Button
        ValvesButton                   matlab.ui.control.Button
        TestSequenceButtonGroup        matlab.ui.container.ButtonGroup
        ExtendButton_2                 matlab.ui.control.RadioButton
        TiltButton                     matlab.ui.control.RadioButton
        LiftButton_2                   matlab.ui.control.RadioButton
        BasketRotateButton_2           matlab.ui.control.RadioButton
        TurretButton_2                 matlab.ui.control.RadioButton
        ExtendTiltButton               matlab.ui.control.RadioButton
        TrackButton                    matlab.ui.control.RadioButton
        JibButton                      matlab.ui.control.RadioButton
        ChassisButton_2                matlab.ui.control.RadioButton
        FullButton                     matlab.ui.control.RadioButton
        ModelButtonGroup               matlab.ui.container.ButtonGroup
        AbstractFlexModelButton        matlab.ui.control.RadioButton
        FlexBoomMotionButton           matlab.ui.control.RadioButton
        AbstractModelButton            matlab.ui.control.RadioButton
        FullSystemLabel                matlab.ui.control.Label
        ExtendButton                   matlab.ui.control.RadioButton
        BasketRotateButton             matlab.ui.control.RadioButton
        TurretButton                   matlab.ui.control.RadioButton
        LiftButton                     matlab.ui.control.RadioButton
        Jib1DButton                    matlab.ui.control.RadioButton
        Jib3DButton                    matlab.ui.control.RadioButton
        BasketMotionButton             matlab.ui.control.RadioButton
        BoomButton                     matlab.ui.control.RadioButton
        ChassisButton                  matlab.ui.control.RadioButton
        FullButton_2                   matlab.ui.control.RadioButton
        HydraulicsActuatorDesignLabel  matlab.ui.control.Label
        MotionTestsActuatorRequirementsLabel  matlab.ui.control.Label
        BasketLevelButton              matlab.ui.control.RadioButton
        HydrModelButton                matlab.ui.control.RadioButton
        BasketLevelTiltButton          matlab.ui.control.RadioButton
        TrackButton_2                  matlab.ui.control.RadioButton
        SkyManifoldButton              matlab.ui.control.RadioButton
        OutriggerHydrButton            matlab.ui.control.RadioButton
        GroundManifoldButton           matlab.ui.control.RadioButton
        ResultsTab                     matlab.ui.container.Tab
        ChassisHydraulicsOutriggerButton  matlab.ui.control.Button
        ChassisHydraulicsTrackButton   matlab.ui.control.Button
        BoomHydraulicsLiftExtendJibButton  matlab.ui.control.Button
        BoomHydraulicsTiltBasketLevelButton  matlab.ui.control.Button
        BoomHydraulicsTurretBasketRtButton  matlab.ui.control.Button
        ChassisHydraulicsOutriggerCodeButton  matlab.ui.control.Button
        ChassisHydraulicsLabel         matlab.ui.control.Label
        BoomHydraulicsLabel            matlab.ui.control.Label
        ChassisHydraulicsTrackCodeButton  matlab.ui.control.Button
        BoomHydraulicsLiftExtendJibCodeButton  matlab.ui.control.Button
        BoomHydraulicsTiltBasketLevelCodeButton  matlab.ui.control.Button
        BoomHydraulicsTurretBasketRtCodeButton  matlab.ui.control.Button
        ChassisMotionLabel             matlab.ui.control.Label
        BoomMotionTurretBasketRtCodeButton  matlab.ui.control.Button
        BoomTurretBasketRtButton       matlab.ui.control.Button
        BoomMotionLabel                matlab.ui.control.Label
        BoomMotionLiftExtendJibCodeButton  matlab.ui.control.Button
        BoomMotionTiltBasketLevelCodeButton  matlab.ui.control.Button
        ChassisMotionOutriggerCodeButton  matlab.ui.control.Button
        ChassisMotionTrackCodeButton   matlab.ui.control.Button
        BoomMotionLiftExtendJibButton  matlab.ui.control.Button
        BoomMotionTiltBasketLevelButton  matlab.ui.control.Button
        ChassisMotionOutriggerButton   matlab.ui.control.Button
        ChassisMotionTrackButton       matlab.ui.control.Button
        WorkflowsTab                   matlab.ui.container.Tab
        ListPipelinesCodeButton        matlab.ui.control.Button
        ListFluidPipelinesButton       matlab.ui.control.Button
        ListFluidVolumesCodeButton     matlab.ui.control.Button
        ListFluidVolumesButton         matlab.ui.control.Button
        SolverCodeButton               matlab.ui.control.Button
        SolverDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        GenerateOperatingPointButton   matlab.ui.control.Button
        BoomLiftCrawlerVirtualTestLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelButtonGroup.SelectedObject;
            assignin('base','trb',selectedButton)
            
            switch (event.NewValue)
                case app.AbstractModelButton,       hname = 'abstract model';
                case app.HydrModelButton,           hname = 'hydr model';
                case app.AbstractFlexModelButton,   hname = 'abstract flex model';
                case app.FullButton_2,              hname = 'full motion';
                case app.BasketMotionButton,        hname = 'basket motion';
                case app.FlexBoomMotionButton,      hname = 'flex boom motion';
                case app.BoomButton,                hname = 'boom motion';
                case app.ChassisButton,             hname = 'chassis motion';
                case app.GroundManifoldButton,      hname = 'ground manifold hydr';
                case app.OutriggerHydrButton,       hname = 'outrigger hydr';
                case app.TrackButton_2,             hname = 'track hydr';
                case app.Jib3DButton,               hname = 'jib hydr 3d';
                case app.Jib1DButton,               hname = 'jib hydr 1d';
                case app.SkyManifoldButton,         hname = 'sky manifold hydr';
                case app.TurretButton,              hname = 'turret hydr';
                case app.ExtendButton,              hname = 'extend hydr';
                case app.LiftButton,                hname = 'lift hydr';
                case app.BasketRotateButton,        hname = 'basket rotate hydr';
                case app.BasketLevelButton,         hname = 'basket level hydr';
                case app.BasketLevelTiltButton,     hname = 'basket level tilt hydr';
            end
            str = ['sm_boom_lift_crawler_harness_open(''' hname ''');'];
            disp(str)
            evalin('base',str);
        end

        % Button pushed function: ChassisMotionOutriggerButton
        function ChassisMotionOutriggerButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot01chassispf_outr';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ChassisMotionTrackButton
        function ChassisMotionTrackButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot02chassispf_track';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: BoomMotionLiftExtendJibButton
        function BoomMotionLiftExtendJibButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot03boompf_lift';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: BoomMotionTiltBasketLevelButton
        function BoomMotionTiltBasketLevelButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot04boompf_tilt';
            disp(str);
            evalin('base',str);
        end

        % Selection changed function: TestSequenceButtonGroup
        function TestSequenceButtonGroupSelectionChanged(app, event)
            selectedButton = app.TestSequenceButtonGroup.SelectedObject;
            str = ['sm_boom_lift_crawler_settest(''' selectedButton.Text ' test'');'];
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ChassisMotionTrackCodeButton
        function ChassisMotionTrackCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot02chassispf_track.m
        end

        % Button pushed function: ChassisMotionOutriggerCodeButton
        function ChassisMotionOutriggerCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot01chassispf_outr.m
        end

        % Button pushed function: BoomMotionTiltBasketLevelCodeButton
        function BoomMotionTiltBasketLevelCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot04boompf_tilt.m
        end

        % Button pushed function: BoomMotionLiftExtendJibCodeButton
        function BoomMotionLiftExtendJibCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot03boompf_lift.m
        end

        % Button pushed function: PlotValveSequenceCodeButton
        function PlotValveSequenceCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot11valveseq.m
        end

        % Button pushed function: GenerateOperatingPointButton
        function GenerateOperatingPointButtonPushed(app, event)
            edit sm_boom_lift_crawler_create_op.m
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;
            str =  ['sm_boom_lift_crawler_setsolver(bdroot,''' value ''');'];
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: SolverCodeButton
        function SolverCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_setsolver.m
        end

        % Button pushed function: ListFluidVolumesButton
        function ListFluidVolumesButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_list_fluidvolumes';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: ListFluidVolumesCodeButton
        function ListFluidVolumesCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_list_fluidvolumes.m
        end

        % Button pushed function: ListFluidPipelinesButton
        function ListFluidPipelinesButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_list_pipelines';
            disp(str);
            evalin('base',str);            
        end

        % Button pushed function: ListPipelinesCodeButton
        function ListPipelinesCodeButtonPushed(app, event)
            edit  sm_boom_lift_crawler_list_pipelines.m
        end

        % Button pushed function: ValvesButton
        function ValvesButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot11valveseq(tVec,oVec);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: BoomTurretBasketRtButton
        function BoomTurretBasketRtButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot05boompf_rotate';
            disp(str);
            evalin('base',str);            
        end

        % Button pushed function: BoomMotionTurretBasketRtCodeButton
        function BoomMotionTurretBasketRtCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot05boompf_rotate.m
        end

        % Button pushed function: ChassisHydraulicsOutriggerButton
        function ChassisHydraulicsOutriggerButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot06chassispab_outr';
            disp(str);
            evalin('base',str);   
        end

        % Button pushed function: ChassisHydraulicsTrackButton
        function ChassisHydraulicsTrackButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot07chassispab_track';
            disp(str);
            evalin('base',str);               
        end

        % Button pushed function: BoomHydraulicsLiftExtendJibButton
        function BoomHydraulicsLiftExtendJibButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot08boompab_lift';
            disp(str);
            evalin('base',str);               
        end

        % Button pushed function: BoomHydraulicsTiltBasketLevelButton
        function BoomHydraulicsTiltBasketLevelButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot09boompab_tilt';
            disp(str);
            evalin('base',str);               
        end

        % Button pushed function: BoomHydraulicsTurretBasketRtButton
        function BoomHydraulicsTurretBasketRtButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot10boompab_rotate';
            disp(str);
            evalin('base',str);               
        end

        % Button pushed function: ChassisHydraulicsOutriggerCodeButton
        function ChassisHydraulicsOutriggerCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot06chassispab_outr.m            
        end

        % Button pushed function: ChassisHydraulicsTrackCodeButton
        function ChassisHydraulicsTrackCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot07chassispab_track.m            
        end

        % Button pushed function: BoomHydraulicsLiftExtendJibCodeButton
        function BoomHydraulicsLiftExtendJibCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot08boompab_lift.m            
        end

        % Button pushed function: BoomHydraulicsTiltBasketLevelCodeButton
        function BoomHydraulicsTiltBasketLevelCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot09boompab_tilt.m            
        end

        % Button pushed function: BoomHydraulicsTurretBasketRtCodeButton
        function BoomHydraulicsTurretBasketRtCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot10boompab_rotate.m            
        end

        % Button pushed function: MotionButton
        function MotionButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot12motionseq(motion)';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotMotionSequenceCodeButton
        function PlotMotionSequenceCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot12motionseq.m
        end

        % Button pushed function: OperatorButton
        function OperatorButtonPushed(app, event)
            str = 'sm_boom_lift_crawler_plot13operatorseq(operator_seq);';
            disp(str);
            evalin('base',str);
        end

        % Button pushed function: PlotControlSequenceCodeButton
        function PlotControlSequenceCodeButtonPushed(app, event)
            edit sm_boom_lift_crawler_plot13operatorseq.m
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BoomLiftCrawlerAppUIFigure = uifigure('Visible', 'off');
            app.BoomLiftCrawlerAppUIFigure.Name = 'Boom Lift Crawler App';
            app.BoomLiftCrawlerAppUIFigure.Icon = 'sm_boom_lift_crawler_gitlab_thumbnail_lifted.png';
            app.Image = uiimage(app.BoomLiftCrawlerAppUIFigure);
            app.Image.ImageSource = 'sm_boom_lift_crawler_full_side01.png';
            app.BoomLiftCrawlerVirtualTestLabel = uilabel(app.BoomLiftCrawlerAppUIFigure);
            app.BoomLiftCrawlerVirtualTestLabel.Text = {'Boom Lift Crawler'; 'Virtual Test'};
            app.TabGroup = uitabgroup(app.BoomLiftCrawlerAppUIFigure);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.ModelButtonGroup = uibuttongroup(app.TestTab);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.GroundManifoldButton = uiradiobutton(app.ModelButtonGroup);
            app.GroundManifoldButton.Tooltip = {'Test hydraulic actuators for outriggers and track.'};
            app.GroundManifoldButton.Text = 'Ground Manifold';
            app.OutriggerHydrButton = uiradiobutton(app.ModelButtonGroup);
            app.OutriggerHydrButton.Tooltip = {'Test hydraulic actuators for one outrigger.'};
            app.OutriggerHydrButton.Text = 'Outrigger';
            app.SkyManifoldButton = uiradiobutton(app.ModelButtonGroup);
            app.SkyManifoldButton.Tooltip = {'Test hydraulic actuators for all boom functions.'};
            app.SkyManifoldButton.Text = 'Sky Manifold';
            app.TrackButton_2 = uiradiobutton(app.ModelButtonGroup);
            app.TrackButton_2.Tooltip = {'Test hydraulic actuators that set track separation.'};
            app.TrackButton_2.Text = 'Track';
            app.BasketLevelTiltButton = uiradiobutton(app.ModelButtonGroup);
            app.BasketLevelTiltButton.Tooltip = {'Test hydraulic actuators for boom tilt and basket leveling.'};
            app.BasketLevelTiltButton.Text = 'Basket Level Tilt';
            app.HydrModelButton = uiradiobutton(app.ModelButtonGroup);
            app.HydrModelButton.Tooltip = {'Test entire hydraulic system'};
            app.HydrModelButton.Text = 'Hydraulic';
            app.BasketLevelButton = uiradiobutton(app.ModelButtonGroup);
            app.BasketLevelButton.Tooltip = {'Test closed hydraulic system that keeps basket level.'};
            app.BasketLevelButton.Text = 'Basket Level';
            app.MotionTestsActuatorRequirementsLabel = uilabel(app.ModelButtonGroup);
            app.MotionTestsActuatorRequirementsLabel.Text = 'Motion Tests  (Actuator Requirements)';
            app.HydraulicsActuatorDesignLabel = uilabel(app.ModelButtonGroup);
            app.HydraulicsActuatorDesignLabel.Text = 'Hydraulics      (Actuator Design)';
            app.FullButton_2 = uiradiobutton(app.ModelButtonGroup);
            app.FullButton_2.Tooltip = {'Use prescribed motion for all actuators.'};
            app.FullButton_2.Text = 'Full';
            app.ChassisButton = uiradiobutton(app.ModelButtonGroup);
            app.ChassisButton.Tooltip = {'Use prescribed motion for outriggers and tracks.'};
            app.ChassisButton.Text = 'Chassis';
            app.BoomButton = uiradiobutton(app.ModelButtonGroup);
            app.BoomButton.Tooltip = {'Use prescribed motion for all boom actuators.'};
            app.BoomButton.Text = 'Boom';
            app.BasketMotionButton = uiradiobutton(app.ModelButtonGroup);
            app.BasketMotionButton.Tooltip = {'Use prescribed motion for jib and basket rotation.'};
            app.BasketMotionButton.Text = 'Basket';
            app.Jib3DButton = uiradiobutton(app.ModelButtonGroup);
            app.Jib3DButton.Tooltip = {'Test the hydraulic actuator for the jib with a 3D mechanical load.'};
            app.Jib3DButton.Text = 'Jib 3D';
            app.Jib1DButton = uiradiobutton(app.ModelButtonGroup);
            app.Jib1DButton.Tooltip = {'Test the hydraulic actuator for the jib with a simplified mechanical load (1D).'};
            app.Jib1DButton.Text = 'Jib 1D';
            app.LiftButton = uiradiobutton(app.ModelButtonGroup);
            app.LiftButton.Tooltip = {'Test hydraulic actuator that raises the telescopic arm and basket.'};
            app.LiftButton.Text = 'Lift';
            app.TurretButton = uiradiobutton(app.ModelButtonGroup);
            app.TurretButton.Tooltip = {'Test hydraulic actuator that sets boom orientation relative to chassis.'};
            app.TurretButton.Text = 'Turret';
            app.BasketRotateButton = uiradiobutton(app.ModelButtonGroup);
            app.BasketRotateButton.Tooltip = {'Test hydraulic system that controls basket orientation relative to boom.'};
            app.BasketRotateButton.Text = 'Basket Rotate';
            app.ExtendButton = uiradiobutton(app.ModelButtonGroup);
            app.ExtendButton.Tooltip = {'Test hydraulic actuator that extends the telescopic arm.'};
            app.ExtendButton.Text = 'Extend';
            app.FullSystemLabel = uilabel(app.ModelButtonGroup);
            app.FullSystemLabel.Text = 'Full System';
            app.AbstractModelButton = uiradiobutton(app.ModelButtonGroup);
            app.AbstractModelButton.Tooltip = {'Test control algorithms with predefined sequences or dashboard blocks.'};
            app.AbstractModelButton.Text = 'Abstract';
            app.AbstractModelButton.Value = true;
            app.FlexBoomMotionButton = uiradiobutton(app.ModelButtonGroup);
            app.FlexBoomMotionButton.Tooltip = {'Model boom segments as flexible bodies.'};
            app.FlexBoomMotionButton.Text = 'Flex Boom';
            app.AbstractFlexModelButton = uiradiobutton(app.ModelButtonGroup);
            app.AbstractFlexModelButton.Tooltip = {'Test control algorithms with predefined sequences or dashboard blocks.'};
            app.AbstractFlexModelButton.Text = 'Flex Boom';
            app.TestSequenceButtonGroup = uibuttongroup(app.TestTab);
            app.TestSequenceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestSequenceButtonGroupSelectionChanged, true);
            app.TestSequenceButtonGroup.Title = 'Test Sequence';
            app.FullButton = uiradiobutton(app.TestSequenceButtonGroup);
            app.FullButton.Text = 'Full';
            app.FullButton.Value = true;
            app.ChassisButton_2 = uiradiobutton(app.TestSequenceButtonGroup);
            app.ChassisButton_2.Text = 'Chassis';
            app.JibButton = uiradiobutton(app.TestSequenceButtonGroup);
            app.JibButton.Text = 'Jib';
            app.TrackButton = uiradiobutton(app.TestSequenceButtonGroup);
            app.TrackButton.Text = 'Track';
            app.ExtendTiltButton = uiradiobutton(app.TestSequenceButtonGroup);
            app.ExtendTiltButton.Text = 'Extend & Tilt';
            app.TurretButton_2 = uiradiobutton(app.TestSequenceButtonGroup);
            app.TurretButton_2.Text = 'Turret';
            app.BasketRotateButton_2 = uiradiobutton(app.TestSequenceButtonGroup);
            app.BasketRotateButton_2.Text = 'Basket Rotate';
            app.LiftButton_2 = uiradiobutton(app.TestSequenceButtonGroup);
            app.LiftButton_2.Text = 'Lift';
            app.TiltButton = uiradiobutton(app.TestSequenceButtonGroup);
            app.TiltButton.Text = 'Tilt';
            app.ExtendButton_2 = uiradiobutton(app.TestSequenceButtonGroup);
            app.ExtendButton_2.Text = 'Extend';
            app.ValvesButton = uibutton(app.TestTab, 'push');
            app.ValvesButton.ButtonPushedFcn = createCallbackFcn(app, @ValvesButtonPushed, true);
            app.ValvesButton.Tooltip = {'Plot the current test sequence as direct commands to the hydraulic valves.'};
            app.ValvesButton.Text = 'Valves';
            app.PlotValveSequenceCodeButton = uibutton(app.TestTab, 'push');
            app.PlotValveSequenceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotValveSequenceCodeButtonPushed, true);
            app.PlotValveSequenceCodeButton.Tooltip = {'See code for plotting valve test sequence.'};
            app.PlotValveSequenceCodeButton.Text = 'Code';
            app.MotionButton = uibutton(app.TestTab, 'push');
            app.MotionButton.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.MotionButton.Tooltip = {'Plot the current test sequence as prescribed motions for the actuators.'};
            app.MotionButton.Text = 'Motion';
            app.PlotMotionSequenceCodeButton = uibutton(app.TestTab, 'push');
            app.PlotMotionSequenceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMotionSequenceCodeButtonPushed, true);
            app.PlotMotionSequenceCodeButton.Tooltip = {'See code for plotting prescribed motion test sequence.'};
            app.PlotMotionSequenceCodeButton.Text = 'Code';
            app.PlotControlSequenceCodeButton = uibutton(app.TestTab, 'push');
            app.PlotControlSequenceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotControlSequenceCodeButtonPushed, true);
            app.PlotControlSequenceCodeButton.Tooltip = {'See code for plotting operator test sequence.'};
            app.PlotControlSequenceCodeButton.Text = 'Code';
            app.OperatorButton = uibutton(app.TestTab, 'push');
            app.OperatorButton.ButtonPushedFcn = createCallbackFcn(app, @OperatorButtonPushed, true);
            app.OperatorButton.Tooltip = {'Plot the current test sequence as actions the operator would take on the control panel.'};
            app.OperatorButton.Text = 'Operator';
            app.PlotTestSequenceLabel = uilabel(app.TestTab);
            app.PlotTestSequenceLabel.Text = {'Plot Test'; 'Sequence'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ChassisMotionTrackButton = uibutton(app.ResultsTab, 'push');
            app.ChassisMotionTrackButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisMotionTrackButtonPushed, true);
            app.ChassisMotionTrackButton.Text = 'Track';
            app.ChassisMotionOutriggerButton = uibutton(app.ResultsTab, 'push');
            app.ChassisMotionOutriggerButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisMotionOutriggerButtonPushed, true);
            app.ChassisMotionOutriggerButton.Text = 'Outrigger';
            app.BoomMotionTiltBasketLevelButton = uibutton(app.ResultsTab, 'push');
            app.BoomMotionTiltBasketLevelButton.ButtonPushedFcn = createCallbackFcn(app, @BoomMotionTiltBasketLevelButtonPushed, true);
            app.BoomMotionTiltBasketLevelButton.Text = 'Tilt, Basket Level';
            app.BoomMotionLiftExtendJibButton = uibutton(app.ResultsTab, 'push');
            app.BoomMotionLiftExtendJibButton.ButtonPushedFcn = createCallbackFcn(app, @BoomMotionLiftExtendJibButtonPushed, true);
            app.BoomMotionLiftExtendJibButton.Text = 'Lift, Extend, Jib';
            app.ChassisMotionTrackCodeButton = uibutton(app.ResultsTab, 'push');
            app.ChassisMotionTrackCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisMotionTrackCodeButtonPushed, true);
            app.ChassisMotionTrackCodeButton.Text = 'Code';
            app.ChassisMotionOutriggerCodeButton = uibutton(app.ResultsTab, 'push');
            app.ChassisMotionOutriggerCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisMotionOutriggerCodeButtonPushed, true);
            app.ChassisMotionOutriggerCodeButton.Text = 'Code';
            app.BoomMotionTiltBasketLevelCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomMotionTiltBasketLevelCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomMotionTiltBasketLevelCodeButtonPushed, true);
            app.BoomMotionTiltBasketLevelCodeButton.Text = 'Code';
            app.BoomMotionLiftExtendJibCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomMotionLiftExtendJibCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomMotionLiftExtendJibCodeButtonPushed, true);
            app.BoomMotionLiftExtendJibCodeButton.Text = 'Code';
            app.BoomMotionLabel = uilabel(app.ResultsTab);
            app.BoomMotionLabel.Text = 'Boom Motion';
            app.BoomTurretBasketRtButton = uibutton(app.ResultsTab, 'push');
            app.BoomTurretBasketRtButton.ButtonPushedFcn = createCallbackFcn(app, @BoomTurretBasketRtButtonPushed, true);
            app.BoomTurretBasketRtButton.Text = 'Turret, Basket Rt';
            app.BoomMotionTurretBasketRtCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomMotionTurretBasketRtCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomMotionTurretBasketRtCodeButtonPushed, true);
            app.BoomMotionTurretBasketRtCodeButton.Text = 'Code';
            app.ChassisMotionLabel = uilabel(app.ResultsTab);
            app.ChassisMotionLabel.Text = 'Chassis Motion';
            app.BoomHydraulicsTurretBasketRtCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsTurretBasketRtCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsTurretBasketRtCodeButtonPushed, true);
            app.BoomHydraulicsTurretBasketRtCodeButton.Text = 'Code';
            app.BoomHydraulicsTiltBasketLevelCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsTiltBasketLevelCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsTiltBasketLevelCodeButtonPushed, true);
            app.BoomHydraulicsTiltBasketLevelCodeButton.Text = 'Code';
            app.BoomHydraulicsLiftExtendJibCodeButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsLiftExtendJibCodeButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsLiftExtendJibCodeButtonPushed, true);
            app.BoomHydraulicsLiftExtendJibCodeButton.Text = 'Code';
            app.ChassisHydraulicsTrackCodeButton = uibutton(app.ResultsTab, 'push');
            app.ChassisHydraulicsTrackCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisHydraulicsTrackCodeButtonPushed, true);
            app.ChassisHydraulicsTrackCodeButton.Text = 'Code';
            app.BoomHydraulicsLabel = uilabel(app.ResultsTab);
            app.BoomHydraulicsLabel.Text = 'Boom Hydraulics';
            app.ChassisHydraulicsLabel = uilabel(app.ResultsTab);
            app.ChassisHydraulicsLabel.Text = 'Chassis Hydraulics';
            app.ChassisHydraulicsOutriggerCodeButton = uibutton(app.ResultsTab, 'push');
            app.ChassisHydraulicsOutriggerCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisHydraulicsOutriggerCodeButtonPushed, true);
            app.ChassisHydraulicsOutriggerCodeButton.Text = 'Code';
            app.BoomHydraulicsTurretBasketRtButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsTurretBasketRtButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsTurretBasketRtButtonPushed, true);
            app.BoomHydraulicsTurretBasketRtButton.Text = 'Turret, Basket Rt';
            app.BoomHydraulicsTiltBasketLevelButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsTiltBasketLevelButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsTiltBasketLevelButtonPushed, true);
            app.BoomHydraulicsTiltBasketLevelButton.Text = 'Tilt, Basket Level';
            app.BoomHydraulicsLiftExtendJibButton = uibutton(app.ResultsTab, 'push');
            app.BoomHydraulicsLiftExtendJibButton.ButtonPushedFcn = createCallbackFcn(app, @BoomHydraulicsLiftExtendJibButtonPushed, true);
            app.BoomHydraulicsLiftExtendJibButton.Text = 'Lift, Extend, Jib';
            app.ChassisHydraulicsTrackButton = uibutton(app.ResultsTab, 'push');
            app.ChassisHydraulicsTrackButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisHydraulicsTrackButtonPushed, true);
            app.ChassisHydraulicsTrackButton.Text = 'Track';
            app.ChassisHydraulicsOutriggerButton = uibutton(app.ResultsTab, 'push');
            app.ChassisHydraulicsOutriggerButton.ButtonPushedFcn = createCallbackFcn(app, @ChassisHydraulicsOutriggerButtonPushed, true);
            app.ChassisHydraulicsOutriggerButton.Text = 'Outrigger';
            app.WorkflowsTab = uitab(app.TabGroup);
            app.WorkflowsTab.Title = 'Workflows';
            app.GenerateOperatingPointButton = uibutton(app.WorkflowsTab, 'push');
            app.GenerateOperatingPointButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOperatingPointButtonPushed, true);
            app.GenerateOperatingPointButton.Tooltip = {'Open code to generate operating point for hydraulic model.'};
            app.GenerateOperatingPointButton.Text = 'Generate Operating Point';
            app.SolverDropDownLabel = uilabel(app.WorkflowsTab);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.WorkflowsTab);
            app.SolverDropDown.Items = {'Variable Step', 'Fixed Step'};
            app.SolverDropDown.ItemsData = {'variable', 'fixed'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Tooltip = {'Set solver to fixed or variable-step for the model that is currently open.'};
            app.SolverDropDown.Value = 'variable';
            app.SolverCodeButton = uibutton(app.WorkflowsTab, 'push');
            app.SolverCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SolverCodeButtonPushed, true);
            app.SolverCodeButton.Tooltip = {'Edit code to set solver for currently open model.'};
            app.SolverCodeButton.Text = 'Code';
            app.ListFluidVolumesButton = uibutton(app.WorkflowsTab, 'push');
            app.ListFluidVolumesButton.ButtonPushedFcn = createCallbackFcn(app, @ListFluidVolumesButtonPushed, true);
            app.ListFluidVolumesButton.Tooltip = {'Print a list of constant volume hydraulic chambers to the MATLAB command window.'};
            app.ListFluidVolumesButton.Text = 'List Fluid Volumes';
            app.ListFluidVolumesCodeButton = uibutton(app.WorkflowsTab, 'push');
            app.ListFluidVolumesCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ListFluidVolumesCodeButtonPushed, true);
            app.ListFluidVolumesCodeButton.Tooltip = {'See code for listing hydraulic volumes.'};
            app.ListFluidVolumesCodeButton.Text = 'Code';
            app.ListFluidPipelinesButton = uibutton(app.WorkflowsTab, 'push');
            app.ListFluidPipelinesButton.ButtonPushedFcn = createCallbackFcn(app, @ListFluidPipelinesButtonPushed, true);
            app.ListFluidPipelinesButton.Tooltip = {'Print a list of hydraulic pipelines to the MATLAB command window.'};
            app.ListFluidPipelinesButton.Text = 'List Fluid Pipelines';
            app.ListPipelinesCodeButton = uibutton(app.WorkflowsTab, 'push');
            app.ListPipelinesCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ListPipelinesCodeButtonPushed, true);
            app.ListPipelinesCodeButton.Tooltip = {'See code for listing hydraulic pipelines.'};
            app.ListPipelinesCodeButton.Text = 'Code';
            % Show the figure after all components are created
            app.BoomLiftCrawlerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sm_boom_lift_crawler_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BoomLiftCrawlerAppUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.BoomLiftCrawlerAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BoomLiftCrawlerAppUIFigure)
        end
    end
end